classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ClearPointsButton          matlab.ui.control.Button
        SelectPointButton          matlab.ui.control.Button
        YListBox                   matlab.ui.control.ListBox
        YListBoxLabel              matlab.ui.control.Label
        FileSelector               matlab.ui.control.DropDown
        FileSelectorDropDownLabel  matlab.ui.control.Label
        YAxisLabel                 matlab.ui.control.Label
        XAxisLabel                 matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        TextAreaLabel              matlab.ui.control.Label
        XDropDown                  matlab.ui.control.DropDown
        XDropDownLabel             matlab.ui.control.Label
        LoadButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FileTables  % Cell array of individual tables
        Data
        SelectedPoints = [];
    end
    
    
    methods (Access = private)
        
        function updatePlot(app)
            if isempty(app.Data), return; end
        
            try
                xVar = app.XDropDown.Value;
                yVars = app.YListBox.Value;  % Can now be multiple strings
                x = app.Data.(xVar);
        
                cla(app.UIAxes);  % Clear existing plot
                hold(app.UIAxes, 'on');
        
                for i = 1:length(yVars)
                    y = app.Data.(yVars{i});
                    plot(app.UIAxes, x, y, 'DisplayName', yVars{i});
                end
        
                hold(app.UIAxes, 'off');
                legend(app.UIAxes, 'show');
        
                title(app.UIAxes, 'Multiple Y Plots');
                xlabel(app.UIAxes, xVar);
                ylabel(app.UIAxes, 'Y Values');
                grid(app.UIAxes, 'on');
        
            catch err
                app.TextArea.Value = sprintf('Error: %s', err.message);
                cla(app.UIAxes);
            end

            for i = 1:size(app.SelectedPoints, 1)
                plot(app.UIAxes, app.SelectedPoints(i, 1), app.SelectedPoints(i, 2), 'ro', 'MarkerSize', 10, 'LineWidth', 2);
            end
        end

        
        function updateDropdownOptions(app)
            selectedFile = app.FileSelector.Value;
            index = find(strcmp(app.FileSelector.Items, selectedFile));
            if isempty(index), return; end
        
            selectedTable = app.FileTables{index};
            app.Data = selectedTable;  % Current table to use for plotting
        
            columnNames = string(selectedTable.Properties.VariableNames);
            app.XDropDown.Items = columnNames;
            app.YListBox.Items = columnNames;
        
            if ismember("Time", columnNames)
                app.XDropDown.Value = "Time";
            end
            if ismember("AverageSpeed [kph]", columnNames)
                app.YListBox.Value = "AverageSpeed [kph]";
            end
             
            updatePlot(app);
        end
        
        function selectPointOnPlot(app)
            % Get current point on UIAxes
            cp = app.UIAxes.CurrentPoint;
            x = cp(1,1);
            y = cp(1,2);
        
            % Save the point
            app.SelectedPoints(end+1, :) = [x, y];
        
            % Plot selected point
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x, y, 'ro', 'MarkerSize', 10, 'LineWidth', 2);
            hold(app.UIAxes, 'off');
        
            % Display coordinates
            app.TextArea.Value = sprintf('Selected Point %d: (%.2f, %.2f)', size(app.SelectedPoints, 1), x, y);
        
            % If two points are selected, plot zoomed graph
            if size(app.SelectedPoints, 1) == 2
                plotZoomedRegion(app);
            end
        end

        function plotZoomedRegion(app)
            try
                xVar = app.XDropDown.Value;
                yVars = app.YListBox.Value;
                xData = app.Data.(xVar);
        
                % Sort selected X values
                xPoints = sort(app.SelectedPoints(:,1));
                xStart = xPoints(1);
                xEnd = xPoints(2);
        
                % Find data indices between selected points
                idx = (xData >= xStart) & (xData <= xEnd);
        
                % Create new figure for zoomed plot
                f = figure('Name', 'Zoomed Plot', 'NumberTitle', 'off');
                ax = axes(f);
                hold(ax, 'on');
        
                for i = 1:length(yVars)
                    y = app.Data.(yVars{i});
                    plot(ax, xData(idx), y(idx), 'DisplayName', yVars{i});
                end
        
                hold(ax, 'off');
                legend(ax, 'show');
                title(ax, sprintf('Plot between %.2f and %.2f (X)', xStart, xEnd));
                xlabel(ax, xVar);
                ylabel(ax, 'Y Values');
                grid(ax, 'on');
        
            catch err
                uialert(app.UIFigure, err.message, 'Error');
            end

            app.SelectedPoints = [];
        end
        
        function highlightZoomRegion(app)
            if size(app.SelectedPoints, 1) < 2
                app.TextArea.Value = 'Select two points first.';
                return;
            end
            x1 = app.SelectedPoints(1,1);
            x2 = app.SelectedPoints(2,1);
            y1 = app.SelectedPoints(1,2);
            y2 = app.SelectedPoints(2,2);
            xmin = min(x1, x2);
            xmax = max(x1, x2);
            ymin = min(y1, y2);
            ymax = max(y1, y2);
        
            rectangle(app.UIAxes, 'Position', [xmin, ymin, xmax - xmin, ymax - ymin], ...
                'EdgeColor', 'g', 'LineWidth', 2, 'LineStyle', '--');
        end

        function ExportZoomedData(app)
            if isempty(app.Data) || size(app.SelectedPoints, 1) < 2
                app.TextArea.Value = 'Select two points first.';
                return;
            end
            xRange = sort(app.SelectedPoints(:,1));
            xVar = app.XDropDown.Value;
            xData = app.Data.(xVar);
            inRangeIdx = xData >= xRange(1) & xData <= xRange(2);
            zoomedData = app.Data(inRangeIdx, :);
        
            % Save to file
            [file,path] = uiputfile({'*.csv'; '*.mat'}, 'Save Zoomed Data');
            if file == 0, return; end
        
            fullPath = fullfile(path, file);
            [~, ~, ext] = fileparts(fullPath);
            if strcmp(ext, '.csv')
                writetable(zoomedData, fullPath);
            elseif strcmp(ext, '.mat')
                save(fullPath, 'zoomedData');
            end
            app.TextArea.Value = ['Zoomed data saved to: ', fullPath];
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [files, path] = uigetfile('*.csv', 'Select CSV file(s)', 'MultiSelect', 'on');
            if isequal(files, 0)
                return;
            end
        
            if ischar(files)
                files = {files};
            end
        
            app.FileTables = {};  % Reset
            app.FileSelector.Items = {};  % Clear existing file list
        
            for i = 1:length(files)
                fullFilePath = fullfile(path, files{i});
                T = readtable(fullFilePath, 'PreserveVariableNames', true);
                app.FileTables{i} = T;
                app.FileSelector.Items{i} = files{i};
            end
        
            % Display status
            app.TextArea.Value = sprintf("Loaded %d file(s).", length(files));
        
            % Auto-select first file and trigger update
            app.FileSelector.Value = app.FileSelector.Items{1};
            updateDropdownOptions(app);
        end

        % Value changed function: XDropDown
        function XDropDownValueChanged(app, event)
        
                    updatePlot(app);
                    
        end

        % Value changed function: FileSelector
        function FileSelectorValueChanged(app, event)
             updateDropdownOptions(app);
        end

        % Value changed function: YListBox
        function YListBoxValueChanged(app, event)
            updatePlot(app);
            
        end

        % Button pushed function: SelectPointButton
        function SelectPointButtonPushed(app, event)
            app.TextArea.Value = "Click anywhere on the plot to select a point...";
            app.UIFigure.WindowButtonDownFcn = @(src, evt) app.selectPointOnPlot();
        end

        % Button pushed function: ClearPointsButton
        function ClearPointsButtonPushed(app, event)
             app.SelectedPoints = [];
             updatePlot(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.XDropDownLabel = uilabel(app.UIFigure);
            app.XDropDownLabel.Text = 'XDropDown';
            app.XDropDown = uidropdown(app.UIFigure);
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'TextArea';
            app.TextArea = uitextarea(app.UIFigure);
            app.XAxisLabel = uilabel(app.UIFigure);
            app.XAxisLabel.Text = 'X-Axis';
            app.YAxisLabel = uilabel(app.UIFigure);
            app.YAxisLabel.Text = 'Y-Axis';
            app.FileSelectorDropDownLabel = uilabel(app.UIFigure);
            app.FileSelectorDropDownLabel.Text = 'FileSelector';
            app.FileSelector = uidropdown(app.UIFigure);
            app.FileSelector.ValueChangedFcn = createCallbackFcn(app, @FileSelectorValueChanged, true);
            app.YListBoxLabel = uilabel(app.UIFigure);
            app.YListBoxLabel.Text = 'YListBox';
            app.YListBox = uilistbox(app.UIFigure);
            app.YListBox.Multiselect = 'on';
            app.YListBox.ValueChangedFcn = createCallbackFcn(app, @YListBoxValueChanged, true);
            app.YListBox.Value = {'Item 1'};
            app.SelectPointButton = uibutton(app.UIFigure, 'push');
            app.SelectPointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointButtonPushed, true);
            app.SelectPointButton.Text = 'SelectPointButton';
            app.ClearPointsButton = uibutton(app.UIFigure, 'push');
            app.ClearPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPointsButtonPushed, true);
            app.ClearPointsButton.Text = 'ClearPointsButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end